classdef demoApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AmplitudeSlider       matlab.ui.control.Slider
        AmplitudeSliderLabel  matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        DoplotButton          matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = public)
        xData % the x data 
        yData % the y data 
    end
    
    properties (Access = private)
       mytitle= 'Title That Is Required' % the plot title 
    end
    
    methods (Access = private)
        
        function helper(app)
            reset(app.UIAxes)
            app.UIAxes.Title.String=app.mytitle ;
            zData= peaks(app.xData,app.yData)*app.AmplitudeSlider.Value;
            
            switch app.DropDown.Value
                case 'Mesh'
                    mesh(app.UIAxes,app.xData,app.yData,zData)
                case 'Surface'
                    surf(app.UIAxes,app.xData,app.yData,zData)
                    zlim(app.UIAxes,[-400,800])
                case 'Contour'
                    contour(app.UIAxes,app.xData,app.yData,zData)
                    zlim(app.UIAxes,[-400,800])
            end
            title(app.UIAxes,app.mytitle)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              xd =linspace(-2,2,20)
              yd = linspace(0,3,30)
            [app.xData,app.yData] = meshgrid(xd,yd)
            helper(app)
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            helper(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DoplotButton = uibutton(app.UIFigure, 'push');
            app.DoplotButton.Text = 'Do plot';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Surface', 'Mesh', 'Contour'};
            app.DropDown.Value = 'Surface';
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demoApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end