classdef lab12app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        HeartBeatCheckBox  matlab.ui.control.CheckBox
        FilteredCheckBox   matlab.ui.control.CheckBox
        VoltageCheckBox    matlab.ui.control.CheckBox
        StartStopButton    matlab.ui.control.StateButton
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
      TryToUseRealDAQ = 0; %ONLY SET TO 1 if you have the drivers installed!
      sampleRate = 512; %DAQ sample rate
      sesh % DAQ session object (real or simulated)
      filterObj %filter object for processing incoming signal
      winSize = 6; %width of plot window, in seconds

    %data
      time = []; %time vector from DAQ
      data = []; %aquired raw voltage data
      simNI_DAQ %simulated
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          setupTimeObj;
          app.filterObj = designfilt( ...
"bandpassiir", ...
"FilterOrder", 4, ...
"HalfPowerFrequency1", 0.1, ...
"HalfPowerFrequency2", 20, ...
"SampleRate", app.sampleRate);
        end

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)
            value = app.StartStopButton.Value;
            if value == 1
                %start in on button press
                app.data=[];
                app.time=[];
             
                if app.sesh.IsRunning==0
                     app.sesh.startBackground();
                end
                    
            else 
                %end 
                app.sesh.stop();
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
app.sesh.stop(); %stop the data aquisition if it is running
activeTimers = timerfind; %find all running timer functions
if ~isempty(activeTimers) %if there's still a timer running then
 stop(activeTimers); %stop all timers
delete(activeTimers); %delete all timers
end
delete(app) %delete the app window
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartStopButton = uibutton(app.UIFigure, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.VoltageCheckBox = uicheckbox(app.UIFigure);
            app.VoltageCheckBox.Text = 'Voltage';
            app.FilteredCheckBox = uicheckbox(app.UIFigure);
            app.FilteredCheckBox.Text = 'Filtered';
            app.HeartBeatCheckBox = uicheckbox(app.UIFigure);
            app.HeartBeatCheckBox.Text = 'Heart Beat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab12app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end